classdef ESP32 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        MOTORSTOPButton   matlab.ui.control.Button
        MOTORSTARTButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        ESP32_Ip = "192.168.4.1"
        ESP32_Port = 5005
        UdpClient
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                % Initialize the UDP port object
                app.UdpClient = udpport;
                disp('UDP Client successfully initialized at startup.');
                disp(['Tarfet ESP32 IP: ', app.ESP32_IP, ' on Port: ', num2str(app.ESP32_Port)]);
            catch ME
                % If something goes wrong (e.g., port conflict on PC)
                uialert(app.UIFigure, ME.message, 'Network Initalization Error');
            end

        end

        % Button pushed function: MOTORSTARTButton
        function StartButtonPushed(app, event)
            try
               % Direct send using the pre-initialized UDP client
               write(app.UdpClient,uint8('1'), app.ESP32_IP, app.ESP32_Port);
               disp('Sent UDP: 1');
            catch ME
                uialert(app.UIFigure, ME.message,'Transmission Error');

            end
        end

        % Button pushed function: MOTORSTOPButton
        function StopButtonPushed(app, event)
            try
                % Direct send using the pre-initialized UDP client
                write(app.UdpClient,uint8('0'), app.ESP32_IP, app.ESP32_Port);
                disp('Sent UDP: 0');
            catch ME
                uialert(app.UIFigure, ME.message,'Transmission Error');

            end
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MOTORSTARTButton = uibutton(app.UIFigure, 'push');
            app.MOTORSTARTButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.MOTORSTARTButton.Text = 'MOTOR START';
            app.MOTORSTOPButton = uibutton(app.UIFigure, 'push');
            app.MOTORSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.MOTORSTOPButton.Text = 'MOTOR STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESP32

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end